classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        valorLabel                      matlab.ui.control.Label
        EditFieldLabel                  matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        DesplegarvaloresSwitchLabel     matlab.ui.control.Label
        DesplegarvaloresSwitch          matlab.ui.control.Switch
        AnguloenxLabel                  matlab.ui.control.Label
        AnguloyLabel                    matlab.ui.control.Label
        AngulozLabel                    matlab.ui.control.Label
        nivelLabel                      matlab.ui.control.Label
        KPLabel                         matlab.ui.control.Label
        KILabel                         matlab.ui.control.Label
        KDLabel                         matlab.ui.control.Label
        Switch_2Label                   matlab.ui.control.Label
        Switch_2                        matlab.ui.control.Switch
        angyLabel                       matlab.ui.control.Label
        angzLabel                       matlab.ui.control.Label
        Labelnivel                      matlab.ui.control.Label
        Labelkp                         matlab.ui.control.Label
        Labelki                         matlab.ui.control.Label
        Labelkd                         matlab.ui.control.Label
        Button                          matlab.ui.control.Button
        EditField2Label                 matlab.ui.control.Label
        EditField2                      matlab.ui.control.EditField
        VariableaModificarListBoxLabel  matlab.ui.control.Label
        VariableaModificarListBox       matlab.ui.control.ListBox
    end

    
    properties (Access = public)
        Property % Description
        ay = "";
        az = "";
        N = "1000";
        kp = "";
        kd = "";
        ki = "";
        ref = "";
        TivaObj;
        
    end
    
    methods (Access = public)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            delete(instrfind);%evita problemas al abrir y cerrar el puerto
            serialportlist("available")';
            app.TivaObj = serialport('COM5',115200);
            %fopen(TivaObj);
            N2 = str2double(app.N);  % Aumentar el número de datos arriba, para apreciar mejor el efecto.
            
            t = linspace(0,2*pi,N2)';    % no es necesario trasponer, es para tener vectores columna
            %y = cos(10*t);
            K = 10;      % Restricción: K debe ser factor de N. Se puede ajustar el código abajo para
                        % eliminar esta restricción. 
         %   app.valorLabel = fscanf(TivaObj, '%f');   
            %figure(7); clf;
            h7 = plot(app.UIAxes,t,zeros(N2,1));
            xlim(app.UIAxes,[0,t(end)]);
            buffer = zeros(K,1);
            k = 1;
            %while(app.DesplegarvaloresSwitch.Value == "Off")
            while(1)
            N2 = str2double(app.N);
            for n = 1:N2
                %data(1,n) = fscanf(TivaObj, '%f');
                %palabra = num2str(data(1,n));
%                 palabra1 = readline(TivaObj);
%                  while (palabra1 ~= "$")
%                      palabra1 = readline(TivaObj);
%                  end
                palabra = readline(app.TivaObj);
                valores = split(palabra,"&");
                angx = str2double(valores(1));
                
%                 app.ay = valores(2);
%                 app.az = valores(3);
%                 app.N = valores(4);
%                 app.kp = valores(5);
%                 app.ki = valores(6);
%                 app.kd = valores(7);

                app.valorLabel.Text = valores(1);
                
                app.angyLabel.Text = valores(2);
                app.angzLabel.Text = valores(3);
                app.Labelnivel.Text = valores(4);
                app.Labelkp.Text = valores(5);
                app.Labelki.Text = valores(6);
                app.Labelkd.Text = valores(7);
         
                buffer(k) = angx;
                
                if(k == K)
                    
                    h7.YData((n-K+1):n) = buffer;   % Asume que K es factor de N. De lo contrario,
                                                    % hay que hacer ajustes adicionales.
                    drawnow limitrate
                    k = 1;
                else
                    k = k + 1;    
                end
            %    end
                
            end
            
            h7 = plot(app.UIAxes,t,zeros(N2,1));
            xlim(app.UIAxes,[0,t(end)]);
            buffer = zeros(K,1);
            end 
            fclose(app.TivaObj);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
           % value = app.EditField.Value;
           % app.valorLabel = value;
            
        end

        % Value changing function: EditField
        function EditFieldValueChanging(app, event)
          %  changingValue = event.Value;
            
        end

        % Value changed function: DesplegarvaloresSwitch
        function DesplegarvaloresSwitchValueChanged(app, event)
            value = app.DesplegarvaloresSwitch.Value;
            if (app.DesplegarvaloresSwitch.Value == "Off")
                app.EditField.Value = "hola";
            else
                app.EditField.Value = "adios";
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            mensaje = app.EditField2.Value;
            if (app.VariableaModificarListBox.Value == "nivel")
                nivel = append("@a",mensaje,"!");
                writeline(app.TivaObj,nivel);
            elseif (app.VariableaModificarListBox.Value == "kp")
                partes = split(mensaje,".");
                kp1 = append("@b",partes(1),"!");
                writeline(app.TivaObj,kp1);
                kp2 = append("@c",partes(2),"!");
                writeline(app.TivaObj,kp2);
            elseif (app.VariableaModificarListBox.Value == "ki")
                partes = split(mensaje,".");
                ki1 = append("@d",partes(1),"!");
                writeline(app.TivaObj,ki1);
                ki2 = append("@e",partes(2),"!");
                writeline(app.TivaObj,ki2);
            elseif (app.VariableaModificarListBox.Value == "kd")
                partes = split(mensaje,".");
                kd1 = append("@f",partes(1),"!");
                writeline(app.TivaObj,kd1);
                kd2 = append("@g",partes(2),"!");
                writeline(app.TivaObj,kd2);          
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Inclinación')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'grados')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [-90 -45 0 45 90];
            app.UIAxes.YTickLabel = {'-90'; '-45'; '0'; '45'; '90'};
            app.UIAxes.Tag = 'grafica1';
            app.valorLabel = uilabel(app.UIFigure);
            app.valorLabel.Text = 'valor';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.ValueChangingFcn = createCallbackFcn(app, @EditFieldValueChanging, true);
            app.DesplegarvaloresSwitchLabel = uilabel(app.UIFigure);
            app.DesplegarvaloresSwitchLabel.Text = 'Desplegar valores';
            app.DesplegarvaloresSwitch = uiswitch(app.UIFigure, 'slider');
            app.DesplegarvaloresSwitch.ValueChangedFcn = createCallbackFcn(app, @DesplegarvaloresSwitchValueChanged, true);
            app.AnguloenxLabel = uilabel(app.UIFigure);
            app.AnguloenxLabel.Text = 'Angulo en x:';
            app.AnguloyLabel = uilabel(app.UIFigure);
            app.AnguloyLabel.Text = 'Angulo y: ';
            app.AngulozLabel = uilabel(app.UIFigure);
            app.AngulozLabel.Text = 'Angulo z:';
            app.nivelLabel = uilabel(app.UIFigure);
            app.nivelLabel.Text = 'nivel:';
            app.KPLabel = uilabel(app.UIFigure);
            app.KPLabel.Text = 'KP';
            app.KILabel = uilabel(app.UIFigure);
            app.KILabel.Text = 'KI';
            app.KDLabel = uilabel(app.UIFigure);
            app.KDLabel.Text = 'KD';
            app.Switch_2Label = uilabel(app.UIFigure);
            app.Switch_2Label.Text = 'Switch';
            app.Switch_2 = uiswitch(app.UIFigure, 'slider');
            app.angyLabel = uilabel(app.UIFigure);
            app.angyLabel.Text = 'angy';
            app.angzLabel = uilabel(app.UIFigure);
            app.angzLabel.Text = 'angz';
            app.Labelnivel = uilabel(app.UIFigure);
            app.Labelnivel.Text = 'Labelnivel';
            app.Labelkp = uilabel(app.UIFigure);
            app.Labelkp.Text = 'Labelkp';
            app.Labelki = uilabel(app.UIFigure);
            app.Labelki.Text = 'Labelki';
            app.Labelkd = uilabel(app.UIFigure);
            app.Labelkd.Text = 'Labelkd';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.EditField2Label = uilabel(app.UIFigure);
            app.EditField2Label.Text = 'Edit Field2';
            app.EditField2 = uieditfield(app.UIFigure, 'text');
            app.VariableaModificarListBoxLabel = uilabel(app.UIFigure);
            app.VariableaModificarListBoxLabel.Text = {'Variable'; 'a'; 'Modificar'};
            app.VariableaModificarListBox = uilistbox(app.UIFigure);
            app.VariableaModificarListBox.Items = {'nivel', 'kp', 'ki', 'kd'};
            app.VariableaModificarListBox.Value = 'nivel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end